classdef guisimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        breaker_4_label         matlab.ui.control.Label
        unit_motor_2_EditField  matlab.ui.control.EditField
        PEditFieldLabel_6       matlab.ui.control.Label
        P_motor_2_EditField     matlab.ui.control.NumericEditField
        unit_motor_1_EditField  matlab.ui.control.EditField
        P_motor_1_EditField     matlab.ui.control.NumericEditField
        PEditFieldLabel_5       matlab.ui.control.Label
        unit_gen_1_EditField_4  matlab.ui.control.EditField
        P_gen_4_EditField       matlab.ui.control.NumericEditField
        PEditFieldLabel_4       matlab.ui.control.Label
        unit_gen_3_EditField    matlab.ui.control.EditField
        P_gen_3_EditField       matlab.ui.control.NumericEditField
        PEditFieldLabel_3       matlab.ui.control.Label
        unit_gen_2_EditField    matlab.ui.control.EditField
        P_gen_2_EditField       matlab.ui.control.NumericEditField
        PEditFieldLabel_2       matlab.ui.control.Label
        unit_gen_1_EditField    matlab.ui.control.EditField
        P_gen_1_EditField       matlab.ui.control.NumericEditField
        PEditFieldLabel         matlab.ui.control.Label
        simulation_state_label  matlab.ui.control.Label
        breaker_2_label         matlab.ui.control.Label
        breaker_1_label         matlab.ui.control.Label
        breaker_3_label         matlab.ui.control.Label
        breaker_2_img_3         matlab.ui.control.Image
        breaker_2_img_2         matlab.ui.control.Image
        breaker_2_img           matlab.ui.control.Image
        breaker_1_img           matlab.ui.control.Image
        gen_4_img               matlab.ui.control.Image
        gen_3_img               matlab.ui.control.Image
        gen_2_img               matlab.ui.control.Image
        gen_1_img               matlab.ui.control.Image
        trafo_1_label           matlab.ui.control.Label
        trafo_2_label           matlab.ui.control.Label
        condition_3_label       matlab.ui.control.Label
        condition_2_label       matlab.ui.control.Label
        condition_1_label       matlab.ui.control.Label
        breaker_4_button        matlab.ui.control.Button
        breaker_3_button        matlab.ui.control.Button
        breaker_2_button        matlab.ui.control.Button
        breaker_1_button        matlab.ui.control.Button
        gen_4_button            matlab.ui.control.Button
        gen_3_button            matlab.ui.control.Button
        gen_2_button            matlab.ui.control.Button
        gen_1_button            matlab.ui.control.Button
        Image                   matlab.ui.control.Image
    end

    
    properties (Access = private)
        Gen_1_status = 0
        Gen_2_status = 0
        Gen_3_status = 0
        Gen_4_status = 0
        Breaker_1
        Breaker_2
        Motor_1
    end
    
    methods (Access = private)
        
        function change_status_gen(app)
            if(app.Gen_1_status == 1)
                app.gen_1_img.BackgroundColor = 'g';
            else
                app.gen_1_img.BackgroundColor = 'r';
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: gen_1_button
        function gen_1_buttonButtonPushed(app, event)
            if (app.Gen_1_status == 0)
                app.Gen_1_status = 1;
            else
                app.Gen_1_status = 0;
            end
            change_status_gen(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Base Asset.png');
            app.gen_1_button = uibutton(app.UIFigure, 'push');
            app.gen_1_button.ButtonPushedFcn = createCallbackFcn(app, @gen_1_buttonButtonPushed, true);
            app.gen_1_button.Text = 'Start/Stop Gen 1';
            app.gen_2_button = uibutton(app.UIFigure, 'push');
            app.gen_2_button.Text = 'Start/Stop Gen 2';
            app.gen_3_button = uibutton(app.UIFigure, 'push');
            app.gen_3_button.Text = 'Start/Stop Gen 3';
            app.gen_4_button = uibutton(app.UIFigure, 'push');
            app.gen_4_button.Text = 'Start/Stop Gen 4';
            app.breaker_1_button = uibutton(app.UIFigure, 'push');
            app.breaker_1_button.Text = 'Open/Close Breaker 1';
            app.breaker_2_button = uibutton(app.UIFigure, 'push');
            app.breaker_2_button.Text = 'Open/Close Breaker 2';
            app.breaker_3_button = uibutton(app.UIFigure, 'push');
            app.breaker_3_button.Text = 'Open/Close Breaker 3';
            app.breaker_4_button = uibutton(app.UIFigure, 'push');
            app.breaker_4_button.Text = 'Open/Close Breaker 4';
            app.condition_1_label = uilabel(app.UIFigure);
            app.condition_1_label.Text = 'GENERATOR BRAKES POSITION IS OFF';
            app.condition_2_label = uilabel(app.UIFigure);
            app.condition_2_label.Text = 'UNIT BREAKING SYSTEM STATUS IS READY';
            app.condition_3_label = uilabel(app.UIFigure);
            app.condition_3_label.Text = 'UNIT AUXILIARIES READY FOR START';
            app.trafo_2_label = uilabel(app.UIFigure);
            app.trafo_2_label.Text = '500/19.5 kV';
            app.trafo_1_label = uilabel(app.UIFigure);
            app.trafo_1_label.Text = '500/19.5 kV';
            app.gen_1_img = uiimage(app.UIFigure);
            app.gen_1_img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Generator.png');
            app.gen_2_img = uiimage(app.UIFigure);
            app.gen_2_img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Generator.png');
            app.gen_3_img = uiimage(app.UIFigure);
            app.gen_3_img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Generator.png');
            app.gen_4_img = uiimage(app.UIFigure);
            app.gen_4_img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Generator.png');
            app.breaker_1_img = uiimage(app.UIFigure);
            app.breaker_1_img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Breaker.png');
            app.breaker_2_img = uiimage(app.UIFigure);
            app.breaker_2_img.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Breaker.png');
            app.breaker_2_img_2 = uiimage(app.UIFigure);
            app.breaker_2_img_2.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Breaker.png');
            app.breaker_2_img_3 = uiimage(app.UIFigure);
            app.breaker_2_img_3.ImageSource = fullfile(pathToMLAPP, 'Assets', 'Breaker.png');
            app.breaker_3_label = uilabel(app.UIFigure);
            app.breaker_3_label.Text = 'Breaker 3';
            app.breaker_1_label = uilabel(app.UIFigure);
            app.breaker_1_label.Text = 'Breaker 1';
            app.breaker_2_label = uilabel(app.UIFigure);
            app.breaker_2_label.Text = 'Breaker 2';
            app.simulation_state_label = uilabel(app.UIFigure);
            app.simulation_state_label.Text = 'SIMULATION';
            app.PEditFieldLabel = uilabel(app.UIFigure);
            app.PEditFieldLabel.Text = 'P:';
            app.P_gen_1_EditField = uieditfield(app.UIFigure, 'numeric');
            app.unit_gen_1_EditField = uieditfield(app.UIFigure, 'text');
            app.unit_gen_1_EditField.Value = 'MW';
            app.PEditFieldLabel_2 = uilabel(app.UIFigure);
            app.PEditFieldLabel_2.Text = 'P:';
            app.P_gen_2_EditField = uieditfield(app.UIFigure, 'numeric');
            app.unit_gen_2_EditField = uieditfield(app.UIFigure, 'text');
            app.unit_gen_2_EditField.Value = 'MW';
            app.PEditFieldLabel_3 = uilabel(app.UIFigure);
            app.PEditFieldLabel_3.Text = 'P:';
            app.P_gen_3_EditField = uieditfield(app.UIFigure, 'numeric');
            app.unit_gen_3_EditField = uieditfield(app.UIFigure, 'text');
            app.unit_gen_3_EditField.Value = 'MW';
            app.PEditFieldLabel_4 = uilabel(app.UIFigure);
            app.PEditFieldLabel_4.Text = 'P:';
            app.P_gen_4_EditField = uieditfield(app.UIFigure, 'numeric');
            app.unit_gen_1_EditField_4 = uieditfield(app.UIFigure, 'text');
            app.unit_gen_1_EditField_4.Value = 'MW';
            app.PEditFieldLabel_5 = uilabel(app.UIFigure);
            app.PEditFieldLabel_5.Text = 'P:';
            app.P_motor_1_EditField = uieditfield(app.UIFigure, 'numeric');
            app.unit_motor_1_EditField = uieditfield(app.UIFigure, 'text');
            app.unit_motor_1_EditField.Value = 'MW';
            app.P_motor_2_EditField = uieditfield(app.UIFigure, 'numeric');
            app.PEditFieldLabel_6 = uilabel(app.UIFigure);
            app.PEditFieldLabel_6.Text = 'P:';
            app.unit_motor_2_EditField = uieditfield(app.UIFigure, 'text');
            app.unit_motor_2_EditField.Value = 'MW';
            app.breaker_4_label = uilabel(app.UIFigure);
            app.breaker_4_label.Text = 'Breaker 4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guisimulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end